classdef HCOBE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HCOBEUIFigure                   matlab.ui.Figure
        OutputSettingsPanel             matlab.ui.container.Panel
        SaveMaskCheckBox                matlab.ui.control.CheckBox
        OutputDirectoriesDropDown       matlab.ui.control.DropDown
        OutputDirectoriesDropDownLabel  matlab.ui.control.Label
        FilenamePrefixEditField         matlab.ui.control.EditField
        FilenamePrefixEditFieldLabel    matlab.ui.control.Label
        OperationParametersPanel        matlab.ui.container.Panel
        ReferenceSiteDropDown           matlab.ui.control.DropDown
        ReferenceSiteDropDownLabel      matlab.ui.control.Label
        orLabel                         matlab.ui.control.Label
        SelectMaskButton                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SetThresholdSpinner             matlab.ui.control.Spinner
        SetThresholdSpinnerLabel        matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        DeletetheLatestSiteButton       matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        AddaNewSiteButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        site_num;
        site_file;
        mask_type;
        custom_path;
        all_file_name; % Description
        site_char_num;
        site_image_num;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddaNewSiteButton
        function AddaNewSiteButtonPushed(app, event)
            if ~contains(app.TextArea.Value,'\')
                app.site_num = 1;
                app.all_file_name = [];
                app.site_image_num = [];
                app.site_char_num = [];
            else
                app.site_num = app.site_num+1;
                
            end
            
            site_image_name = spm_select(Inf,'image','Please select the image of site 1');
            [file_num,name_char] = size(site_image_name);
            
            site_file_name = blanks(file_num*name_char-file_num);
            image_folder_struct = cell(file_num,1);
            image_name_struct = cell(file_num,1);
            start_char = 1;
            for file_i = 1:file_num
                subject_image_name = site_image_name(file_i,:);
                image_name = strsplit(subject_image_name,',1');
                subject_char_num = length(image_name{1,1});
                image_info = strsplit(image_name{1,1},'\');
                filder_char_num = subject_char_num-length(image_info{1,end})-1;
                image_name_struct{file_i,1} = image_info{1,end};
                site_file_name(1,start_char:start_char+subject_char_num) = [subject_image_name(1,1:subject_char_num),newline];
                start_char = start_char+subject_char_num+1;
                image_folder_struct{file_i,1} = subject_image_name(1,1:filder_char_num);
            end
            if app.site_num == 1
                app.all_file_name = [app.all_file_name,'Site',num2str(app.site_num),newline,site_file_name];
            else
                app.all_file_name = [app.all_file_name,newline,'Site',num2str(app.site_num),newline,site_file_name];
            end
            app.TextArea.Value = app.all_file_name;
           
            app.site_image_num = [app.site_image_num,file_num];
            
            char_num = length(app.all_file_name);
            if app.site_num == 1
                app.site_char_num = char_num;
            else
                app.site_char_num = [app.site_char_num,char_num-sum(app.site_char_num)];
            end
            
            site_file_struct = struct('folder',image_folder_struct,'name',image_name_struct);
            app.site_file = [app.site_file;site_file_struct];
        end

        % Button pushed function: DeletetheLatestSiteButton
        function DeletetheLatestSiteButtonPushed(app, event)
            delete_image_num = app.site_image_num(1,end);
            app.site_image_num = app.site_image_num(1,1:end-1);
            app.site_file(end-delete_image_num+1:end,:) = [];
            app.all_file_name(end-app.site_char_num(1,end)+1:end) = [];
            app.TextArea.Value = app.all_file_name;
            app.site_char_num = app.site_char_num(1,1:end-1);
        end

        % Button pushed function: SelectMaskButton
        function SelectMaskButtonPushed(app, event)
            mask_name = spm_select(1,'image','Please select a mask image');
            name_char_num = length(mask_name)-2;
            app.mask_type = mask_name(1,1:name_char_num);
            app.EditField.Value = app.mask_type;
        end

        % Value changed function: OutputDirectoriesDropDown
        function OutputDirectoriesDropDownValueChanged(app, event)
            value = app.OutputDirectoriesDropDown.Value;
            if strcmp(value,'Custom Dir')
                app.custom_path = uigetdir('/Users','Please select a folder to store the harmonized data');
            elseif strcmp(value,'Re-Custom Dir')
                app.custom_path = uigetdir('/Users','Please select a folder to store the harmonized data');
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            reference_site = app.ReferenceSiteDropDown.Value;
            reference_site(1:4) = [];
            reference_site = str2num(reference_site);
            
            if ~ischar(app.mask_type)
                app.mask_type = app.SetThresholdSpinner.Value;
            end
            
            prefix_name = app.FilenamePrefixEditField.Value;
            
            
            [original_voxel_matrix, final_matrix,size_matrix,mask] = hcobe(app.site_file,app.site_image_num,app.mask_type,reference_site);
            
            
            image_num = sum(app.site_image_num);
            if strcmp(app.OutputDirectoriesDropDown.Value,'Previous Dir')
                h_image = waitbar(0,'Writing inprocess');
                for subject_i = 1:image_num
                    s=['Writing inprocess:' num2str(ceil(100*subject_i/image_num)) '%'];
                    image_name = [app.site_file(subject_i).folder,'\',app.site_file(subject_i).name];
                    image_header = spm_vol(image_name);
                    image_header.fname = [app.site_file(subject_i).folder '\' prefix_name app.site_file(subject_i).name];
                    harmonize_data = original_voxel_matrix(:,subject_i);
                    harmonize_data(mask~=0,:) = final_matrix(:,subject_i);
                    spm_write_vol(image_header,reshape(harmonize_data,size_matrix));
                    waitbar(subject_i/image_num,h_image,s);
                end
                close(h_image);
                
                if app.SaveMaskCheckBox.Value
                    mask_name = [app.site_file(1).folder,'\',app.site_file(1).name];
                    mask_header = spm_vol(mask_name);
                    mask_header.fname = [app.site_file(1).folder '\' 'MASK.nii'];
                    spm_write_vol(mask_header,reshape(mask,size_matrix));
                end
                
                
            else
                site_number = length(app.site_image_num);
                h_image = waitbar(0,'Writing inprocess');
                
                for site_i = 1:site_number
                    start_image = sum(app.site_image_num(1:site_i-1))+1;
                    end_image = sum(app.site_image_num(1:site_i));
                    
                    for subject_i = start_image:end_image
                        s=['Writing inprocess:' num2str(ceil(100*subject_i/image_num)) '%'];
                        image_name = [app.site_file(subject_i).folder,'\',app.site_file(subject_i).name];
                        image_header = spm_vol(image_name);
                        image_header.fname = [app.custom_path '\' prefix_name 'site' num2str(site_i) '_' app.site_file(subject_i).name];
                        harmonize_data = original_voxel_matrix(:,subject_i);
                        harmonize_data(mask~=0,:) = final_matrix(:,subject_i);
                        spm_write_vol(image_header,reshape(harmonize_data,size_matrix));
                        waitbar(subject_i/image_num,h_image,s);
                    end
                end
                close(h_image);
                
                if app.SaveMaskCheckBox.Value
                    mask_name = [app.site_file(1).folder,'\',app.site_file(1).name];
                    mask_header = spm_vol(mask_name);
                    mask_header.fname = [app.custom_path,'\','MASK.nii'];
                    spm_write_vol(mask.header,reshape(mask,size_matrix));
                end
                
            end
            disp(strcat(datestr(datetime),'-Done    ''Multi-Site Harmonization Has Been Finished'''));
            disp(strcat(datestr(datetime),'-Done'));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HCOBEUIFigure = uifigure('Visible', 'off');
            app.HCOBEUIFigure.Name = 'HCOBE';
            app.AddaNewSiteButton = uibutton(app.HCOBEUIFigure, 'push');
            app.AddaNewSiteButton.ButtonPushedFcn = createCallbackFcn(app, @AddaNewSiteButtonPushed, true);
            app.AddaNewSiteButton.Text = 'Add a New Site';
            app.TextArea = uitextarea(app.HCOBEUIFigure);
            app.DeletetheLatestSiteButton = uibutton(app.HCOBEUIFigure, 'push');
            app.DeletetheLatestSiteButton.ButtonPushedFcn = createCallbackFcn(app, @DeletetheLatestSiteButtonPushed, true);
            app.DeletetheLatestSiteButton.Text = 'Delete the Latest Site';
            app.RunButton = uibutton(app.HCOBEUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OperationParametersPanel = uipanel(app.HCOBEUIFigure);
            app.OperationParametersPanel.Title = 'Operation Parameters';
            app.SetThresholdSpinnerLabel = uilabel(app.OperationParametersPanel);
            app.SetThresholdSpinnerLabel.Text = 'Set Threshold';
            app.SetThresholdSpinner = uispinner(app.OperationParametersPanel);
            app.SetThresholdSpinner.Step = 0.01;
            app.SetThresholdSpinner.Limits = [0 1];
            app.SetThresholdSpinner.Value = 0.2;
            app.EditField = uieditfield(app.OperationParametersPanel, 'text');
            app.SelectMaskButton = uibutton(app.OperationParametersPanel, 'push');
            app.SelectMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMaskButtonPushed, true);
            app.SelectMaskButton.Text = 'Select Mask';
            app.orLabel = uilabel(app.OperationParametersPanel);
            app.orLabel.Text = 'or';
            app.ReferenceSiteDropDownLabel = uilabel(app.OperationParametersPanel);
            app.ReferenceSiteDropDownLabel.Text = 'Reference Site';
            app.ReferenceSiteDropDown = uidropdown(app.OperationParametersPanel);
            app.ReferenceSiteDropDown.Items = {'Site1', 'Site2', 'Site3', 'Site4', 'Site5', 'Site6', 'Site7', 'Site8', 'Site9', 'Site10', 'Site11', 'Site12', 'Site13', 'Site14', 'Site15', 'Site16', 'Site17', 'Site18', 'Site19', 'Site20'};
            app.ReferenceSiteDropDown.Value = 'Site1';
            app.OutputSettingsPanel = uipanel(app.HCOBEUIFigure);
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.FilenamePrefixEditFieldLabel = uilabel(app.OutputSettingsPanel);
            app.FilenamePrefixEditFieldLabel.Text = 'Filename Prefix';
            app.FilenamePrefixEditField = uieditfield(app.OutputSettingsPanel, 'text');
            app.FilenamePrefixEditField.Value = 'h';
            app.OutputDirectoriesDropDownLabel = uilabel(app.OutputSettingsPanel);
            app.OutputDirectoriesDropDownLabel.Text = 'Output Directories';
            app.OutputDirectoriesDropDown = uidropdown(app.OutputSettingsPanel);
            app.OutputDirectoriesDropDown.Items = {'Previous Dir', 'Custom Dir', 'Re-Custom Dir'};
            app.OutputDirectoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoriesDropDownValueChanged, true);
            app.OutputDirectoriesDropDown.Value = 'Previous Dir';
            app.SaveMaskCheckBox = uicheckbox(app.OutputSettingsPanel);
            app.SaveMaskCheckBox.Text = '   Save Mask';
            % Show the figure after all components are created
            app.HCOBEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCOBE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HCOBEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HCOBEUIFigure)
        end
    end
end