classdef PETHarmony < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        OutputSettingsPanel             matlab.ui.container.Panel
        SaveSiteFeatureCheckBox         matlab.ui.control.CheckBox
        OutputDirectoriesDropDown       matlab.ui.control.DropDown
        OutputDirectoriesDropDownLabel  matlab.ui.control.Label
        SaveMaskCheckBox                matlab.ui.control.CheckBox
        FilenamePrefixEditField         matlab.ui.control.EditField
        FilenamePrefixEditFieldLabel    matlab.ui.control.Label
        OperationParametersPanel        matlab.ui.container.Panel
        CovariatesEditFieldCovariates   matlab.ui.control.EditField
        CovariatesEditField_2Label      matlab.ui.control.Label
        SetThresholdSpinner             matlab.ui.control.Spinner
        SetThresholdSpinnerLabel        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        orLabel                         matlab.ui.control.Label
        SelectMaskButton                matlab.ui.control.Button
        ReferenceSiteDropDown           matlab.ui.control.DropDown
        ReferenceSiteDropDownLabel      matlab.ui.control.Label
        PValueEditField                 matlab.ui.control.NumericEditField
        PValueEditFieldLabel            matlab.ui.control.Label
        Site2TextArea                   matlab.ui.control.TextArea
        Site1TextArea                   matlab.ui.control.TextArea
        SelectSite2DataButton           matlab.ui.control.Button
        SelectSite1DataButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        site1_file; % Description
        site2_file;
        mask_type;
        site1_path;
        site2_path;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectSite1DataButton
        function SelectSite1DataButtonPushed(app, event)
            site1_image_name = spm_select(Inf,'image','Please select the image of site 1');
            [file1_num,name_char] = size(site1_image_name);
            
            site1_file_name = blanks(file1_num*name_char-file1_num);
            image_folder_struct = cell(file1_num,1);
            image_name_struct = cell(file1_num,1);
            start_char = 1;
            for file1_i = 1:file1_num
                subject_image_name = site1_image_name(file1_i,:);
                image_name = strsplit(subject_image_name,',1');
                subject_char_num = length(image_name{1,1});
                image_info = strsplit(image_name{1,1},'\');
                filder_char_num = subject_char_num-length(image_info{1,end})-1;
                image_name_struct{file1_i,1} = image_info{1,end};
                site1_file_name(1,start_char:start_char+subject_char_num) = [subject_image_name(1,1:subject_char_num),newline];
                start_char = start_char+subject_char_num+1;
                image_folder_struct{file1_i,1} = subject_image_name(1,1:filder_char_num);
            end
            
            app.Site1TextArea.Value = site1_file_name;
            app.site1_file = struct('folder',image_folder_struct,'name',image_name_struct);
        end

        % Button pushed function: SelectSite2DataButton
        function SelectSite2DataButtonPushed(app, event)
            site2_image_name = spm_select(Inf,'image','Please select the image of site 2');
            [file2_num,name_char] = size(site2_image_name);
            
            site2_file_name = blanks(file2_num*name_char-file2_num);
            image_folder_struct = cell(file2_num,1);
            image_name_struct = cell(file2_num,1);
            start_char = 1;
            for file2_i = 1:file2_num
                subject_image_name = site2_image_name(file2_i,:);
                image_name = strsplit(subject_image_name,',1');
                subject_char_num = length(image_name{1,1});
                image_info = strsplit(image_name{1,1},'\');
                filder_char_num = subject_char_num-length(image_info{1,end})-1;
                image_name_struct{file2_i,1} = image_info{1,end};
                site2_file_name(1,start_char:start_char+subject_char_num) = [subject_image_name(1,1:subject_char_num),newline];
                start_char = start_char+subject_char_num+1;
                image_folder_struct{file2_i,1} = subject_image_name(1,1:filder_char_num);
            end
            
            app.Site2TextArea.Value = site2_file_name;
            app.site2_file = struct('folder',image_folder_struct,'name',image_name_struct);
        end

        % Value changed function: SetThresholdSpinner
        function SetThresholdSpinnerValueChanged(app, event)
            app.mask_type = 0.1;            
        end

        % Button pushed function: SelectMaskButton
        function SelectMaskButtonPushed(app, event)
            mask_name = spm_select(1,'image','Please select a mask image');
            name_char_num = length(mask_name)-2;
            app.mask_type = mask_name(1,1:name_char_num);
            app.TextArea.Value = app.mask_type;
        end

        % Value changed function: OutputDirectoriesDropDown
        function OutputDirectoriesDropDownValueChanged(app, event)
            value = app.OutputDirectoriesDropDown.Value;
            if strcmp(value,'Custom Dir')
                app.site1_path = uigetdir('/Users','Please select a folder to store the harmonized data');
                app.site2_path = app.site1_path;
            elseif strcmp(value,'Re-Custom Dir')
                app.site1_path = uigetdir('/Users','Please select a folder to store the harmonized data');
                app.site2_path = app.site1_path;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Set parameters
            if strcmp(app.ReferenceSiteDropDown.Value,'Site 1')
                reference_site = 1;
            else
                reference_site = 2;
            end
            
            
            if ~ischar(app.mask_type)
                app.mask_type = app.SetThresholdSpinner.Value;
            end
            
            P = app.PValueEditField.Value;
            
            prefix_name = app.FilenamePrefixEditField.Value;
            
            if strcmp(app.OutputDirectoriesDropDown.Value,'Previous Dir')
                app.site1_path = app.site1_file(1).folder;
                app.site2_path = app.site2_file(1).folder;
            end
            
            
            first_image_info = spm_vol([app.site1_file(1).folder,'\',app.site1_file(1).name]);
            [dim_x,dim_y,dim_z] = size(spm_read_vols(first_image_info));
            site1_image_num = length(app.site1_file);
            site2_image_num = length(app.site2_file);
            image_num = site1_image_num+site2_image_num;
            harmonize_data = zeros(1,prod([dim_x,dim_y,dim_z]));
            
            % SVD
            if ~isempty(app.CovariatesEditFieldCovariates.Value)
                covariate_info = evalin('base',app.CovariatesEditFieldCovariates.Value);
                [original_data,covariate_data,subject_score,gismatrix,mask_up0] = ssm_pca(app.site1_file,app.site2_file,app.mask_type,covariate_info);  %新添original_data
            elseif isempty(app.CovariatesEditFieldCovariates.Value)
                [original_data,covariate_data,subject_score,gismatrix,mask_up0] = ssm_pca(app.site1_file,app.site2_file,app.mask_type);    %新添original_data
            end
            
            
            % Harmonization
            Ttest = harmonize_image(subject_score,gismatrix,site1_image_num);
            subject_score = subject_score(:,Ttest(:,1));
            gismatrix = gismatrix(:,Ttest(:,1));
            subject_score_har = harmonize_score(subject_score,site1_image_num,Ttest,reference_site,P);
            
            
            % Write image
            gismatrix_n = sum(Ttest(:,2)<=P);
            har_data = subject_score_har*gismatrix'+covariate_data';
            
            
            if strcmp(app.OutputDirectoriesDropDown.Value,'Previous Dir')
                h_image = waitbar(0,'Writing inprocess');
                switch reference_site
                    case 1
                        for subject_i = site1_image_num+1:image_num
                            s=['Writing inprocess:' num2str(ceil(100*(subject_i-site1_image_num)/site2_image_num)) '%'];
                            first_image_info.fname = [app.site2_file(subject_i-site1_image_num).folder '\' prefix_name app.site2_file(subject_i-site1_image_num).name];
                            harmonize_data = original_data(subject_i,:);                         %新添
                            harmonize_data(:,mask_up0~=0) = har_data(subject_i,:);
                            spm_write_vol(first_image_info,reshape(harmonize_data,dim_x,dim_y,dim_z));
                            waitbar((subject_i-site1_image_num)/site2_image_num,h_image,s);
                        end
                        close(h_image);
                        
                        if app.SaveMaskCheckBox.Value
                            first_image_info.fname = [app.site2_path '\' 'MASK.nii'];
                            spm_write_vol(first_image_info,reshape(mask_up0,dim_x,dim_y,dim_z));
                        end
                        
                        if app.SaveSiteFeatureCheckBox.Value
                            for site_gismatrix_i = 1:gismatrix_n
                                mask_up0(mask_up0~=0) = gismatrix(:,site_gismatrix_i);
                                first_image_info.fname = [app.site2_path '\' 'site_feature_' num2str(site_gismatrix_i) '.nii'];
                                spm_write_vol(first_image_info,reshape(mask_up0,dim_x,dim_y,dim_z));
                            end
                            
                        end
                        
                        
                    case 2
                        for subject_i = 1:site1_image_num
                            s=['Writing inprocess:' num2str(ceil(100*subject_i/site1_image_num)) '%'];
                            first_image_info.fname = [app.site1_file(subject_i).folder '\' prefix_name app.site1_file(subject_i).name];
                            harmonize_data(:,mask_up0~=0) = har_data(subject_i,:);
                            spm_write_vol(first_image_info,reshape(harmonize_data,dim_x,dim_y,dim_z));
                            waitbar(subject_i/site1_image_num,h_image,s);
                        end
                        close(h_image);
                        
                        if app.SaveMaskCheckBox.Value
                            first_image_info.fname = [app.site1_path '\' 'MASK.nii'];
                            spm_write_vol(first_image_info,reshape(mask_up0,dim_x,dim_y,dim_z));
                        end
                        
                        if app.SaveSiteFeatureCheckBox.Value
                            for site_gismatrix_i = 1:gismatrix_n
                                mask_up0(mask_up0~=0) = gismatrix(:,site_gismatrix_i);
                                first_image_info.fname = [app.site1_path '\' 'site_feature_' num2str(site_gismatrix_i) '.nii'];
                                spm_write_vol(first_image_info,reshape(mask_up0,dim_x,dim_y,dim_z));
                            end
                        end
                        
                end
                
                
            else
                h_image = waitbar(0,'Writing inprocess');
                switch reference_site
                    case 1
                        for subject_i = site1_image_num+1:image_num
                            s=['Writing inprocess:' num2str(ceil(100*(subject_i-site1_image_num)/site2_image_num)) '%'];
                            first_image_info.fname = [app.site1_path '\' prefix_name 'site2_' app.site2_file(subject_i-site1_image_num).name];
                            harmonize_data = original_data(subject_i,:);                         %新添
                            harmonize_data(:,mask_up0~=0) = har_data(subject_i,:);
                            spm_write_vol(first_image_info,reshape(harmonize_data,dim_x,dim_y,dim_z));
                            waitbar((subject_i-site1_image_num)/site2_image_num,h_image,s);
                        end
                        close(h_image);
                        
                    case 2
                        for subject_i = 1:site1_image_num
                            s=['Writing inprocess:' num2str(ceil(100*subject_i/site1_image_num)) '%'];
                            first_image_info.fname = [app.site1_path '\' prefix_name 'site1_' app.site1_file(subject_i).name];
                            harmonize_data = original_data(subject_i,:);                         %新添
                            harmonize_data(:,mask_up0~=0) = har_data(subject_i,:);
                            spm_write_vol(first_image_info,reshape(harmonize_data,dim_x,dim_y,dim_z));
                            waitbar(subject_i/site1_image_num,h_image,s);
                        end
                        close(h_image);
                        
                end
                
               
                if app.SaveMaskCheckBox.Value
                    first_image_info.fname = [app.site1_path '\' 'MASK.nii'];
                    spm_write_vol(first_image_info,reshape(mask_up0,dim_x,dim_y,dim_z));
                end
                
                
                if app.SaveSiteFeatureCheckBox.Value
                    for site_gismatrix_i = 1:gismatrix_n
                        mask_up0(mask_up0~=0) = gismatrix(:,site_gismatrix_i);
                        first_image_info.fname = [app.site1_path '\' 'site_feature_' num2str(site_gismatrix_i) '.nii'];
                        spm_write_vol(first_image_info,reshape(mask_up0,dim_x,dim_y,dim_z));
                    end
                    
                end
                
            end
            disp(strcat(datestr(datetime),'-Done    ''Multi-Site Harmonization Has Been Finished'''));
            disp(strcat(datestr(datetime),'-Done'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectSite1DataButton = uibutton(app.UIFigure, 'push');
            app.SelectSite1DataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSite1DataButtonPushed, true);
            app.SelectSite1DataButton.Text = 'Select Site 1 Data';
            app.SelectSite2DataButton = uibutton(app.UIFigure, 'push');
            app.SelectSite2DataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSite2DataButtonPushed, true);
            app.SelectSite2DataButton.Text = 'Select Site 2 Data';
            app.Site1TextArea = uitextarea(app.UIFigure);
            app.Site2TextArea = uitextarea(app.UIFigure);
            app.OperationParametersPanel = uipanel(app.UIFigure);
            app.OperationParametersPanel.Title = 'Operation Parameters';
            app.PValueEditFieldLabel = uilabel(app.OperationParametersPanel);
            app.PValueEditFieldLabel.Text = 'P Value';
            app.PValueEditField = uieditfield(app.OperationParametersPanel, 'numeric');
            app.PValueEditField.Limits = [0 Inf];
            app.PValueEditField.Value = 0.05;
            app.ReferenceSiteDropDownLabel = uilabel(app.OperationParametersPanel);
            app.ReferenceSiteDropDownLabel.Text = 'Reference Site';
            app.ReferenceSiteDropDown = uidropdown(app.OperationParametersPanel);
            app.ReferenceSiteDropDown.Items = {'Site 1', 'Site 2'};
            app.ReferenceSiteDropDown.Value = 'Site 1';
            app.SelectMaskButton = uibutton(app.OperationParametersPanel, 'push');
            app.SelectMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMaskButtonPushed, true);
            app.SelectMaskButton.Text = 'Select Mask ';
            app.orLabel = uilabel(app.OperationParametersPanel);
            app.orLabel.Text = 'or';
            app.TextArea = uitextarea(app.OperationParametersPanel);
            app.SetThresholdSpinnerLabel = uilabel(app.OperationParametersPanel);
            app.SetThresholdSpinnerLabel.Text = 'Set Threshold';
            app.SetThresholdSpinner = uispinner(app.OperationParametersPanel);
            app.SetThresholdSpinner.Step = 0.01;
            app.SetThresholdSpinner.Limits = [0 1];
            app.SetThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @SetThresholdSpinnerValueChanged, true);
            app.SetThresholdSpinner.Value = 0.2;
            app.CovariatesEditField_2Label = uilabel(app.OperationParametersPanel);
            app.CovariatesEditField_2Label.Text = 'Covariates';
            app.CovariatesEditFieldCovariates = uieditfield(app.OperationParametersPanel, 'text');
            app.OutputSettingsPanel = uipanel(app.UIFigure);
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.FilenamePrefixEditFieldLabel = uilabel(app.OutputSettingsPanel);
            app.FilenamePrefixEditFieldLabel.Text = 'Filename Prefix';
            app.FilenamePrefixEditField = uieditfield(app.OutputSettingsPanel, 'text');
            app.FilenamePrefixEditField.Value = 'h';
            app.SaveMaskCheckBox = uicheckbox(app.OutputSettingsPanel);
            app.SaveMaskCheckBox.Text = 'Save Mask';
            app.OutputDirectoriesDropDownLabel = uilabel(app.OutputSettingsPanel);
            app.OutputDirectoriesDropDownLabel.Text = 'Output Directories';
            app.OutputDirectoriesDropDown = uidropdown(app.OutputSettingsPanel);
            app.OutputDirectoriesDropDown.Items = {'Previous Dir', 'Custom Dir', 'Re-Custom Dir'};
            app.OutputDirectoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoriesDropDownValueChanged, true);
            app.OutputDirectoriesDropDown.Value = 'Previous Dir';
            app.SaveSiteFeatureCheckBox = uicheckbox(app.OutputSettingsPanel);
            app.SaveSiteFeatureCheckBox.Text = 'Save Site Feature';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PETHarmony

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end