classdef ROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        OutputSettingsPanel             matlab.ui.container.Panel
        OutputDirectoriesDropDown       matlab.ui.control.DropDown
        OutputDirectoriesDropDownLabel  matlab.ui.control.Label
        FilenamePrefixEditField         matlab.ui.control.EditField
        FilenamePrefixEditFieldLabel    matlab.ui.control.Label
        OperationParametersPanel        matlab.ui.container.Panel
        CovariatesEditFieldCovariates   matlab.ui.control.EditField
        CovariatesEditField_2Label      matlab.ui.control.Label
        ReferenceSiteDropDown           matlab.ui.control.DropDown
        ReferenceSiteDropDownLabel      matlab.ui.control.Label
        PValueEditField                 matlab.ui.control.NumericEditField
        PValueEditFieldLabel            matlab.ui.control.Label
        Site2TextArea                   matlab.ui.control.TextArea
        Site1TextArea                   matlab.ui.control.TextArea
        SelectSite2DataButton           matlab.ui.control.Button
        SelectSite1DataButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        site1_file; % Description
        site2_file;
        mask_type;
        site1_path;
        site2_path;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectSite1DataButton
        function SelectSite1DataButtonPushed(app, event)
            [file, path] = uigetfile({'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'; '*.*', 'All Files (*.*)'}, 'Please select the radiomics file for Site 1.');
            app.Site1TextArea.Value = fullfile(path, file);
        end

        % Button pushed function: SelectSite2DataButton
        function SelectSite2DataButtonPushed(app, event)
            [file, path] = uigetfile({'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'; '*.*', 'All Files (*.*)'}, 'Please select the radiomics file for Site 1.');
            app.Site2TextArea.Value = fullfile(path, file);
        end

        % Value changed function: OutputDirectoriesDropDown
        function OutputDirectoriesDropDownValueChanged(app, event)
            value = app.OutputDirectoriesDropDown.Value;
            if strcmp(value,'Custom Dir')
                app.site1_path = uigetdir('/Users','Please select a folder to store the harmonized data');
                app.site2_path = app.site1_path;
            elseif strcmp(value,'Re-Custom Dir')
                app.site1_path = uigetdir('/Users','Please select a folder to store the harmonized data');
                app.site2_path = app.site1_path;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Set parameters
            if strcmp(app.ReferenceSiteDropDown.Value,'Site 1')
                reference_site = 1;
            else
                reference_site = 2;
            end
           
            P = app.PValueEditField.Value;
            
            prefix_name = app.FilenamePrefixEditField.Value;
            
            if strcmp(app.OutputDirectoriesDropDown.Value,'Previous Dir')
                [app.site1_path, ~, ~] = fileparts(app.Site1TextArea.Value);
                [app.site2_path, ~, ~] = fileparts(app.Site2TextArea.Value);
            end

            [site1_data,~,site1_info] = xlsread(string(app.Site1TextArea.Value));
            [site2_data,~,site2_info] = xlsread(string(app.Site2TextArea.Value));
  
            [~, site1_image_num] = size(site1_data);
            [~, image_num] = size([site1_data site2_data]);
            
            % SVD
            if ~isempty(app.CovariatesEditFieldCovariates.Value)
                covariate_info = evalin('base',app.CovariatesEditFieldCovariates.Value);
                [covariate_data,subject_score,gismatrix] = ssm_pca_radiomics(string(app.Site1TextArea.Value),string(app.Site2TextArea.Value),covariate_info);  %新添original_data
            elseif isempty(app.CovariatesEditFieldCovariates.Value)
                [covariate_data,subject_score,gismatrix] = ssm_pca_radiomics(string(app.Site1TextArea.Value),string(app.Site2TextArea.Value));    %新添original_data
            end
            
            
            % Harmonization
            Ttest = harmonize_image(subject_score,gismatrix,site1_image_num);
            subject_score = subject_score(:,Ttest(:,1));
            gismatrix = gismatrix(:,Ttest(:,1));
            subject_score_har = harmonize_score(subject_score,site1_image_num,Ttest,reference_site,P);
            
            
            % Write image
            gismatrix_n = sum(Ttest(:,2)<=P);
            har_data = subject_score_har*gismatrix'+covariate_data';
            
            
            if strcmp(app.OutputDirectoriesDropDown.Value,'Previous Dir')
                switch reference_site
                    case 1
                        harmonize_data = har_data(site1_image_num+1:image_num,:);
                        site2_info(2:end,2:end) = num2cell(harmonize_data');
                        [path, name, ext] = fileparts(string(app.Site2TextArea.Value));
                        newFileName = strcat(prefix_name, name, ext);
                        newFilePath = fullfile(path, newFileName);
                        writecell(site2_info, string(newFilePath));
                        
                    case 2
                        harmonize_data = har_data(1:site1_image_num,:);
                        site1_info(2:end,2:end) = num2cell(harmonize_data');
                        [path, name, ext] = fileparts(string(app.Site1TextArea.Value));
                        newFileName = strcat(prefix_name, name, ext);
                        newFilePath = fullfile(path, newFileName);
                        writecell(site1_info, newFilePath);

                end
                
                
            else
                switch reference_site
                    case 1
                        harmonize_data = har_data(site1_image_num+1:image_num,:);
                        site2_info(2:end,2:end) = num2cell(harmonize_data');
                        [~, name, ext] = fileparts(string(app.Site2TextArea.Value));
                        newFileName = strcat(prefix_name, name, ext);
                        newFilePath = fullfile(app.site1_path, newFileName);
                        writecell(site2_info, newFilePath);
                        
                    case 2
                        harmonize_data = har_data(1:site1_image_num,:);
                        site1_info(2:end,2:end) = num2cell(harmonize_data');
                        [~, name, ext] = fileparts(string(app.Site1TextArea.Value));
                        newFileName = strcat(prefix_name, name, ext);
                        newFilePath = fullfile(app.site1_path, newFileName);
                        writecell(site1_info, newFilePath);                      
                end
                
            end
            disp(strcat(datestr(datetime),'-Done    ''Multi-Site Harmonization Has Been Finished'''));
            disp(strcat(datestr(datetime),'-Done'));
        end

        % Value changed function: Site1TextArea
        function Site1TextAreaValueChanged(app, event)
            value = class(app.Site1TextArea.Value);
            
        end

        % Value changed function: Site2TextArea
        function Site2TextAreaValueChanged(app, event)
            value = app.Site2TextArea.Value;
            
        end

        % Value changing function: Site1TextArea
        function Site1TextAreaValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectSite1DataButton = uibutton(app.UIFigure, 'push');
            app.SelectSite1DataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSite1DataButtonPushed, true);
            app.SelectSite1DataButton.Text = 'Select Site 1 Data';
            app.SelectSite2DataButton = uibutton(app.UIFigure, 'push');
            app.SelectSite2DataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSite2DataButtonPushed, true);
            app.SelectSite2DataButton.Text = 'Select Site 2 Data';
            app.Site1TextArea = uitextarea(app.UIFigure);
            app.Site1TextArea.ValueChangedFcn = createCallbackFcn(app, @Site1TextAreaValueChanged, true);
            app.Site1TextArea.ValueChangingFcn = createCallbackFcn(app, @Site1TextAreaValueChanging, true);
            app.Site2TextArea = uitextarea(app.UIFigure);
            app.Site2TextArea.ValueChangedFcn = createCallbackFcn(app, @Site2TextAreaValueChanged, true);
            app.OperationParametersPanel = uipanel(app.UIFigure);
            app.OperationParametersPanel.Title = 'Operation Parameters';
            app.PValueEditFieldLabel = uilabel(app.OperationParametersPanel);
            app.PValueEditFieldLabel.Text = 'P Value';
            app.PValueEditField = uieditfield(app.OperationParametersPanel, 'numeric');
            app.PValueEditField.Limits = [0 Inf];
            app.PValueEditField.Value = 0.05;
            app.ReferenceSiteDropDownLabel = uilabel(app.OperationParametersPanel);
            app.ReferenceSiteDropDownLabel.Text = 'Reference Site';
            app.ReferenceSiteDropDown = uidropdown(app.OperationParametersPanel);
            app.ReferenceSiteDropDown.Items = {'Site 1', 'Site 2'};
            app.ReferenceSiteDropDown.Value = 'Site 1';
            app.CovariatesEditField_2Label = uilabel(app.OperationParametersPanel);
            app.CovariatesEditField_2Label.Text = 'Covariates';
            app.CovariatesEditFieldCovariates = uieditfield(app.OperationParametersPanel, 'text');
            app.OutputSettingsPanel = uipanel(app.UIFigure);
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.FilenamePrefixEditFieldLabel = uilabel(app.OutputSettingsPanel);
            app.FilenamePrefixEditFieldLabel.Text = 'Filename Prefix';
            app.FilenamePrefixEditField = uieditfield(app.OutputSettingsPanel, 'text');
            app.FilenamePrefixEditField.Value = 'h';
            app.OutputDirectoriesDropDownLabel = uilabel(app.OutputSettingsPanel);
            app.OutputDirectoriesDropDownLabel.Text = 'Output Directories';
            app.OutputDirectoriesDropDown = uidropdown(app.OutputSettingsPanel);
            app.OutputDirectoriesDropDown.Items = {'Previous Dir', 'Custom Dir', 'Re-Custom Dir'};
            app.OutputDirectoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoriesDropDownValueChanged, true);
            app.OutputDirectoriesDropDown.Value = 'Previous Dir';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end